classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CaptureImageButton         matlab.ui.control.Button
        UploadImageButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        NewImageButton             matlab.ui.control.Button
        TrainedConversionButton    matlab.ui.control.Button
        UntrainedConversionButton  matlab.ui.control.Button
        ConversionTextAreaLabel    matlab.ui.control.Label
        ConversionTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        webcamObject; % Description
        imageObject;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.webcamObject = webcam;
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            delete(app.webcamObject);
            delete(app.imageObject);
            [filename, directory] = uigetfile('*.png;*.jpg;*.bmp;*.tif');
            complete_name = fullfile(directory, filename);
            TheImage = imread(complete_name);
            imshow(TheImage, 'parent', app.UIAxes);
            
            
            % edit image and save
            [rows, columns, numberOfColorChannels] = size(TheImage);
            if numberOfColorChannels > 1
                I = rgb2gray(TheImage);               % It's a true color RGB image.  We need to convert to gray scale.
            else
                I = TheImage;                         % It's already gray scale.  No need to convert.
            end
            CS = imadjust(I,stretchlim(I),[]);   %Contrast Stretching
            II = imadjust(CS,[],[],.41);         %Increase Intensity
            
            BW = imbinarize(I,'adaptive','ForegroundPolarity','dark','Sensitivity',0.4);%Binary The Image
            imwrite(BW, 'cleaned_img.jpg');
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
%             img=snapshot(app.webcamObject);
%             imwrite(img, 'orig_img.jpg');
%             
%             % edit image and save
%             [rows, columns, numberOfColorChannels] = size(img);
%             if numberOfColorChannels > 1
%                 I = rgb2gray(img);               % It's a true color RGB image.  We need to convert to gray scale.
%             else
%                 I = img;                         % It's already gray scale.  No need to convert.
%             end
%             CS = imadjust(I,stretchlim(I),[]);   %Contrast Stretching
%             II = imadjust(CS,[],[],.41);         %Increase Intensity
%             
%             BW = imbinarize(I,'adaptive','ForegroundPolarity','dark','Sensitivity',0.4);%Binary The Image
%             imwrite(BW,'cleaned_img.jpg');
% %             imwrite(BW,'TrainingData/3rdTry/vivian.jpg');
% 
%             
%             imshow(img, 'parent', app.UIAxes);
%             delete(app.webcamObject);
%             delete(app.imageObject);

            
            val = '';
            
            while strlength(val) < 5
                % edit image and save
                img=snapshot(app.webcamObject);
            
                [rows, columns, numberOfColorChannels] = size(img);
                if numberOfColorChannels > 1
                    I = rgb2gray(img);               % It's a true color RGB image.  We need to convert to gray scale.
                else
                    I = img;                         % It's already gray scale.  No need to convert.
                end
                CS = imadjust(I,stretchlim(I),[]);   %Contrast Stretching
                II = imadjust(CS,[],[],.41);         %Increase Intensity
                
                BW = imbinarize(I,'adaptive','ForegroundPolarity','dark','Sensitivity',0.4);%Binary The Image
                results = ocr(BW,'Language','myLang/tessdata/myLang.traineddata');
                val = results.Text;
                while contains(val, ' ')
                    val = strrep(val, ' ', '');
                end  
                while contains(val, '\n')
                    disp("True");
                    val = regexprep(val,'[\n\r]+','');
                end 
                
                %                 imwrite(BW,'cleaned_img.jpg');
%             imwrite(BW,'TrainingData/3rdTry/vivian.jpg');
            end
            disp("1");
            disp(strlength(val));
            disp(val);
            imwrite(BW,'cleaned_img.jpg');
            imwrite(img, 'orig_img.jpg');
            imshow(img, 'parent', app.UIAxes);
            delete(app.webcamObject);
            delete(app.imageObject);
           
           
        end

        % Button pushed function: NewImageButton
        function NewImageButtonPushed(app, event)
            app.webcamObject = webcam;
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
        end

        % Button pushed function: TrainedConversionButton
        function TrainedConversionButtonPushed(app, event)
            BW = imread('cleaned_img.jpg');
            results = ocr(BW,'Language','myLang/tessdata/myLang.traineddata');
            app.ConversionTextArea.Value = results.Text;
        end

        % Button pushed function: UntrainedConversionButton
        function UntrainedConversionButtonPushed(app, event)
            BW = imread('cleaned_img.jpg');
            results = ocr(BW, 'CharacterSet', 'abcdefghijklmnopqrstuvwxyzABCDEFGHIJKLMNOPQRSTUVWXYZ', 'TextLayout','Block');
            app.ConversionTextArea.Value = results.Text;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CaptureImageButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.UIAxes = uiaxes(app.UIFigure);
            app.NewImageButton = uibutton(app.UIFigure, 'push');
            app.NewImageButton.ButtonPushedFcn = createCallbackFcn(app, @NewImageButtonPushed, true);
            app.NewImageButton.Text = 'New Image';
            app.TrainedConversionButton = uibutton(app.UIFigure, 'push');
            app.TrainedConversionButton.ButtonPushedFcn = createCallbackFcn(app, @TrainedConversionButtonPushed, true);
            app.TrainedConversionButton.Text = 'Trained Conversion';
            app.UntrainedConversionButton = uibutton(app.UIFigure, 'push');
            app.UntrainedConversionButton.ButtonPushedFcn = createCallbackFcn(app, @UntrainedConversionButtonPushed, true);
            app.UntrainedConversionButton.Text = 'Untrained Conversion ';
            app.ConversionTextAreaLabel = uilabel(app.UIFigure);
            app.ConversionTextAreaLabel.Text = 'Conversion:';
            app.ConversionTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end